classdef Classificatore_GDC_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassificatoreperGDCDataUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConnessioneTab                  matlab.ui.container.Tab
        VerificaDatabaseSourceinMatLABPanel  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        VerificaButton_3                matlab.ui.control.Button
        OpzioniDropDown                 matlab.ui.control.DropDown
        OpzioniDropDownLabel            matlab.ui.control.Label
        VerificaDBeTabellePanel         matlab.ui.container.Panel
        CancellaTabellageniButton       matlab.ui.control.Button
        CreaTabellageniButton           matlab.ui.control.Button
        CancellaTabelleButton           matlab.ui.control.Button
        CreaTabelleButton               matlab.ui.control.Button
        VerificastatusAPIGDCDataPortalPanel  matlab.ui.container.Panel
        StatusLamp_2                    matlab.ui.control.Lamp
        StatusLamp_2Label               matlab.ui.control.Label
        VerificaButton                  matlab.ui.control.Button
        ConnessioneServerMySQLPanel     matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        ConnettiButton                  matlab.ui.control.Button
        DownloaddatiTab                 matlab.ui.container.Tab
        FasiperlapopolazionediMySQLPanel  matlab.ui.container.Panel
        InserisciRNASeqneldatabaseButton  matlab.ui.control.Button
        ScaricaRNASeqdeicasiButton      matlab.ui.control.Button
        ScaricacasiprogettoButton       matlab.ui.control.Button
        InseriscidatideicasineldatabaseButton  matlab.ui.control.Button
        SelezionareilprogettodellostudioDropDown  matlab.ui.control.DropDown
        SelezionareilprogettodellostudioDropDownLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        VisualizzaJSONButton            matlab.ui.control.Button
        StatusDownloadTextArea          matlab.ui.control.TextArea
        PreprocessingTab                matlab.ui.container.Tab
        AumentoRiduzionecampionitrainingsetPanel  matlab.ui.container.Panel
        UndersamplingButton             matlab.ui.control.Button
        OversamplingButton              matlab.ui.control.Button
        NormalizzazionedatiPanel        matlab.ui.container.Panel
        NormalizzadatiButton            matlab.ui.control.Button
        CreazionedatidiTraineTestPanel  matlab.ui.container.Panel
        DivididatasetButton             matlab.ui.control.Button
        TestSlider                      matlab.ui.control.Slider
        TestSliderLabel                 matlab.ui.control.Label
        TrainSlider                     matlab.ui.control.Slider
        TrainSliderLabel                matlab.ui.control.Label
        TargettizzazionedatasetgravenongraveButton  matlab.ui.control.Button
        ScaricaredatidellaselezioneButton  matlab.ui.control.Button
        MalattieListBox                 matlab.ui.control.ListBox
        MalattieListBoxLabel            matlab.ui.control.Label
        RecuperainformazioniprogettoButton  matlab.ui.control.Button
        RiduzionedimensionalitTab       matlab.ui.container.Tab
        FeaturedaconservarePanel        matlab.ui.container.Panel
        QuantefeatureconservareSpinner  matlab.ui.control.Spinner
        QuantefeatureconservareSpinnerLabel  matlab.ui.control.Label
        ConservaButton                  matlab.ui.control.Button
        StatistichePanel                matlab.ui.container.Panel
        AlphaSpinner                    matlab.ui.control.Spinner
        AlphaSpinnerLabel               matlab.ui.control.Label
        MetododicorrezioneDropDown      matlab.ui.control.DropDown
        MetododicorrezioneDropDownLabel  matlab.ui.control.Label
        CorrezionepValueCheckBox        matlab.ui.control.CheckBox
        CalcolareFoldChangeCheckBox     matlab.ui.control.CheckBox
        EseguiTestIpotesiButton         matlab.ui.control.Button
        SelezionedelleFeaturesLabel     matlab.ui.control.Label
        RiduzionedelleFeaturesLabel     matlab.ui.control.Label
        tDistributedStochasticNeighborEmbeddingPanel  matlab.ui.container.Panel
        PerplexitySpinner               matlab.ui.control.Spinner
        PerplexitySpinnerLabel          matlab.ui.control.Label
        NumerodifeaturesdapreservareSpinner  matlab.ui.control.Spinner
        NumerodifeaturesdapreservareSpinnerLabel  matlab.ui.control.Label
        EseguitSNEButton                matlab.ui.control.Button
        PrincipalComponentAnalysisPanel  matlab.ui.container.Panel
        VarianzadapreservareSpinner     matlab.ui.control.Spinner
        VarianzadapreservareSpinnerLabel  matlab.ui.control.Label
        EseguiPCAButton                 matlab.ui.control.Button
        AnalisiTab                      matlab.ui.container.Tab
        EseguiaddestramentosenzaGAButton  matlab.ui.control.Button
        AlgoritmoGeneticoPanel          matlab.ui.container.Panel
        MostraIterazioniCheckBox        matlab.ui.control.CheckBox
        NumerodigenerezionimaxSpinner   matlab.ui.control.Spinner
        NumerodigenerezionimaxSpinnerLabel  matlab.ui.control.Label
        NumerodistallimaxSpinner        matlab.ui.control.Spinner
        NumerodistallimaxSpinnerLabel   matlab.ui.control.Label
        LimiteFunzFitnessSpinner        matlab.ui.control.Spinner
        LimiteFunzFitnessSpinnerLabel   matlab.ui.control.Label
        IndividuiElitSpinner            matlab.ui.control.Spinner
        IndividuiElitSpinnerLabel       matlab.ui.control.Label
        PopolazioneSpinner              matlab.ui.control.Spinner
        PopolazioneSpinnerLabel         matlab.ui.control.Label
        EseguiaddestramentoconGAButton  matlab.ui.control.Button
        ReteNeuralePanel                matlab.ui.container.Panel
        IterazionimassimeSpinner        matlab.ui.control.Spinner
        IterazionimassimeLabel          matlab.ui.control.Label
        NeuroniLayer2Spinner            matlab.ui.control.Spinner
        NeuroniLayer2SpinnerLabel       matlab.ui.control.Label
        NeuroniLayer1Spinner            matlab.ui.control.Spinner
        NeuroniLayer1SpinnerLabel       matlab.ui.control.Label
    end

    properties (Access = private) %Rende accessibile l'attributo a tutte le funzioni 
        connection 
        response_cases
        response_seq
        response_genes
        analysis_table
        query_analysis_table_1 = ['SELECT * FROM (SELECT subjects.case_id, ajcc_pathologic_stage, ajcc_pathologic_t, ajcc_pathologic_n, ajcc_pathologic_m FROM subjects join diagnoses on subjects.case_id = diagnoses.case_id where (disease_type =']
        query_analysis_table_2 = [' )) as c join genes_expression on genes_expression.case_id = c.case_id ;']
        total_cases
        severe_cases_full
        minor_cases_full
        gene_table_val
        total_cases_randorder_array
        % Dataset
        x_train
        t_train
        x_test 
        t_test
        
        table_stats_ui
    end
    
    properties (Access = public)
        JSONrequestData = '{"size":"2000","filters":{"op":"in","content":{"field":"project.project_id","value":"TCGA-LUAD"}},"format":"json","expand":"diagnoses,diagnoses.treatments,demographic,exposures"}';
        JSONrequestRNASeq = '{"filters":{"op":"and","content":[{"op":"in","content":{"field":"cases.case_id","value":[]}},{"op":"in","content":{"field":"analysis.workflow_type","value":["HTSeq - Counts"]}},{"op":"in","content": {"field":"experimental_strategy","value":["RNA-Seq"]}}]},"format":"json","field": "file_id, file_name","expand": "cases", "size":"2000"}';
        JSONrequestGeneList = '{"filters":{"op":"and","content":[{"op":"in","content":{"field":"cases.project.program.name","value":["TCGA"]}},{"op":"in","content":{"field":"is_cancer_gene_census","value":["true"]}},{"op":"in","content": {"field":"genes.biotype","value":["protein_coding"]}}]},"format":"json","size":"1000"}';
    end
    
    methods (Access = private)
        
        function fixed_string = fix_missing_params(app, params_query)
            for i = 1:size(params_query,2)
                if isempty(params_query{1,i})
                    params_query{1,i} = missing;
                end
            end
            fixed_string = params_query;
        end
        
        % Funzione che permette, tramite l'interazione dell'utente, di
        % modificare la tabella dei geni con le statistiche
        function updateTable(app, event) 
            app.gene_table_val = app.table_stats_ui.DisplayData;
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnettiButton
        function ConnettiButtonPushed(app, event)
            % Questa connessione viene utilizzata in tutta l'app
            app.connection = database(app.OpzioniDropDown.Value, app.UsernameEditField.Value, app.PasswordEditField.Value); 
            if (isopen(app.connection) == 1)
                app.StatusLamp.Color = [0 1 0];
                app.CreaTabelleButton.Enable = 'on';
                app.CancellaTabelleButton.Enable = 'on';
                app.ScaricacasiprogettoButton.Enable = 'on';
                app.ScaricacasiprogettoButton.BackgroundColor = [.72 .91 .58];
                app.CreaTabellageniButton.Enable = 'on';
                app.CancellaTabellageniButton.Enable = 'on';
                app.RecuperainformazioniprogettoButton.Enable = 'on';
            else
                app.StatusLamp.Color = [1 0 0];
                uialert(app.ClassificatoreperGDCDataUIFigure,'Qualcosa è andato storto. Controlla di aver immesso i dati correttamente e che il server sia attivo', 'Errore');
            end
        end

        % Button pushed function: VerificaButton_3
        function VerificaButton_3Pushed(app, event)
            list = table2array(listDataSources()); %Tabella 5*3 dove ci interessa solo la prima colonna
            app.OpzioniDropDown.Items = list(:,1);
            % Viene modificata l'opzione di default e abilitati i label per la connessione
            if app.OpzioniDropDown.Items{1, 1} ~= "Verificare opzioni disponibili" 
                app.UsernameEditField.Editable = 'on';
                app.PasswordEditField.Editable = 'on';
                app.ConnettiButton.Enable = 'on';
            end
        end

        % Button pushed function: CreaTabelleButton
        function CreaTabelleButtonPushed(app, event)
            try
                sqlquery_1 = ['create table if not exists subjects ( case_id varchar(100) primary key, disease_type varchar(100), primary_site varchar(100))'];
                execute(app.connection,sqlquery_1);
                sqlquery_2 = ['create table if not exists demographic ('...
                             'demographic_id varchar(100) primary key,'...
                             'race varchar(100),'...
                             'days_to_birth int,'...
                             'ethnicity varchar(100),'...
                             'year_of_birth int,'...
                             'vital_status varchar(20),'...
                             'age_at_index int,'...
                             'year_of_death int,'...
                             'gender varchar(20),'...
                             'case_id varchar(100),'...
                             'foreign key (case_id) references subjects(case_id)'...
                             ');'];
                execute(app.connection,sqlquery_2);
                sqlquery_3 = ['create table if not exists exposures ('...
                             'exposure_id varchar(100) primary key,'...
                             'height int,'...
                             'weight int,'...
                             'alcohol_history varchar(20),'...
                             'cigarettes_per_day int,'...
                             'years_smoked int,'...
                             'bmi float,'...
                             'alcohol_intensity varchar(20),'...
                             'case_id varchar(100),'...
                             'foreign key (case_id) references subjects(case_id)'...
                             ');'];
                execute(app.connection,sqlquery_3);
                sqlquery_4 = ['create table if not exists diagnoses ('...
                             'diagnosis_id varchar(100) primary key,'...
                             'site_of_resection_or_biopsy varchar(100),'...
                             'prior_treatment varchar(100),'...
                             'progression_or_recurrence varchar(100),'...
                             'age_at_diagnosis int,'...
                             'ajcc_staging_system_edition varchar(20),'....
                             'days_to_last_known_disease_status int,'...
                             'ajcc_pathologic_t varchar(20),'...
                             'days_to_diagnosis int,'...
                             'days_to_recurrence int,'...
                             'last_known_disease_status varchar(100),'....
                             'ajcc_pathologic_n varchar(20),'...
                             'icd_10_code varchar(20),'...
                             'tissue_or_organ_of_origin varchar(100),'...
                             'ajcc_pathologic_stage varchar(20),'...
                             'tumor_stage varchar(100),'...
                             'tumor_grade varchar(100),'...
                             'year_of_diagnosis int,'...
                             'morphology varchar(20),'...
                             'days_to_last_follow_up int,'...
                             'classification_of_tumor varchar(20),'...
                             'synchronous_malignancy varchar(20),'...
                             'ajcc_pathologic_m varchar(20),'...
                             'prior_malignancy varchar(20),'...
                             'state varchar(20),'...
                             'primary_diagnosis varchar(50),'...
                             'case_id varchar(100),'...
                             'foreign key (case_id) references subjects(case_id)'...
                             ');'];
                execute(app.connection,sqlquery_4);
                sqlquery_5 = ['create table if not exists treatments ('...
                             'treatment_id varchar(100) primary key,'...
                             'treatment_intent_type varchar(100),'...
                             'treatment_or_therapy varchar(100),'...
                             'treatment_type varchar(100),'...
                             'therapeutic_agents varchar(100),'...
                             'treatment_effect varchar(100),'...
                             'treatment_outcome varchar(100),'...
                             'initial_disease_status varchar(100),'...
                             'diagnosis_id varchar(100),'...
                             'foreign key (diagnosis_id) references diagnoses(diagnosis_id)'...
                             ');'];
                execute(app.connection,sqlquery_5);
                uialert(app.ClassificatoreperGDCDataUIFigure,'Tabelle create correttamente','Operazione completata correttamente','Icon','success');   
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: CancellaTabelleButton
        function CancellaTabelleButtonPushed(app, event)
            try
                sqlquery_drop = ['drop table subjects, demographic, exposures, diagnoses, treatments'];
                execute(app.connection,sqlquery_drop);
                uialert(app.ClassificatoreperGDCDataUIFigure,'Tabelle eliminate con successo','Operazione completata correttamente','Icon','success');
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: VerificaButton
        function VerificaButtonPushed(app, event)
            % Formulazione richiesta HTTP con GET
            try
                method = matlab.net.http.RequestMethod.GET;
                destination = "https://api.gdc.cancer.gov/status";
                request = matlab.net.http.RequestMessage(method);
                uri = matlab.net.URI(destination);
                % Invio richiesta
                response_status = send(request,uri);
                if response_status.StatusLine.StatusCode == 200
                    app.StatusLamp_2.Color = [0 1 0];
                else
                    app.StatusLamp_2.Color = [1 0 0];
                end
            catch
                app.StatusLamp_2.Color = [1 0 0];
            end
        end

        % Button pushed function: VisualizzaJSONButton
        function VisualizzaJSONButtonPushed(app, event)
            app.StatusDownloadTextArea.Value = app.JSONrequestData;
        end

        % Value changed function: SelezionareilprogettodellostudioDropDown
        function SelezionareilprogettodellostudioDropDownValueChanged(app, event)
            value = app.SelezionareilprogettodellostudioDropDown.Value;
            app.JSONrequestData = ['{"size":"2000","filters":{"op":"in","content":{"field":"project.project_id","value":"', value,'"}},"format":"json","expand":"diagnoses,diagnoses.treatments,demographic,exposures"}'];
            app.StatusDownloadTextArea.Value = app.JSONrequestData;
        end

        % Button pushed function: ScaricacasiprogettoButton
        function ScaricacasiprogettoButtonPushed(app, event)
            try
                waitbar_1 = waitbar(0,'Attendere...'); %Waitbar per UI
                pause(0.3) %Waitbar per UI
                
                % Richiesta HTTP
                method = matlab.net.http.RequestMethod.POST;
                contentTypeField = matlab.net.http.field.ContentTypeField('application/json');
                header = [contentTypeField];
                body = matlab.net.http.MessageBody(jsondecode(app.JSONrequestData));
                
                waitbar(.33, waitbar_1, 'Formulo la richiesta'); %Waitbar per UI
                
                destination = "https://api.gdc.cancer.gov/cases/";
                request = matlab.net.http.RequestMessage(method, header, body);
                uri = matlab.net.URI(destination);
                
                waitbar(.67, waitbar_1, 'Scaricando i dati dal GDC Data Portal'); %Waitbar per UI
                
                %https://it.mathworks.com/help/matlab/ref/matlab.net.http.requestmessage.send.html
                [app.response_cases] = send(request,uri);
                if app.response_cases.StatusCode == 200
                    waitbar(1, waitbar_1, 'Procedura terminata con successo'); %Waitbar per UI
                    pause(1) %Waitbar per UI
                    close(waitbar_1) %Waitbar per UI
                    app.InseriscidatideicasineldatabaseButton.Enable = 'on';
                    app.InseriscidatideicasineldatabaseButton.BackgroundColor = [.72 .91 .58];
                    app.ScaricacasiprogettoButton.BackgroundColor = [.96 .96 .96]; %Reimportazione colore di default (grigio)
                else 
                    waitbar(1, waitbar_1, 'Procedura non completata. Chiusura in corso'); %Waitbar per UI
                    pause(1.4) %Waitbar per UI
                    close(waitbar_1) %Waitbar per UI
                end
                uialert(app.ClassificatoreperGDCDataUIFigure, 'Casi scaricati correttamente','Operazione completata correttamente','Icon','success');
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
                close(waitbar_1) % Se c'è un errore chiude la waitbar
            end
        end

        % Button pushed function: InseriscidatideicasineldatabaseButton
        function InseriscidatideicasineldatabaseButtonPushed(app, event)
            % Tabelle
            table_name1 = 'subjects';
            table_name2 = 'demographic';
            table_name3 = 'exposures';
            table_name4 = 'diagnoses';
            table_name5 = 'treatments';
            
            % Utilizzo dei prepared statements
            sqlQuery1 = ['INSERT INTO ' table_name1 ' VALUES(?, ?, ?)'];
            sqlQuery2 = ['INSERT INTO ' table_name2 ' VALUES(?, ?, ?, ?, ?, ?, ?, ?, ?, ?)'];
            sqlQuery3 = ['INSERT INTO ' table_name3 ' VALUES(?, ?, ?, ?, ?, ?, ?, ?, ?)'];
            sqlQuery4 = ['INSERT INTO ' table_name4 ' VALUES(?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?, ?)'];
            sqlQuery5 = ['INSERT INTO ' table_name5 ' VALUES(?, ?, ?, ?, ?, ?, ?, ?, ?)'];
            
            try
            data = app.response_cases.Body.Data.data.hits;
            wait_length = size(data,1); %Dato per UI
            waitbar_2 = waitbar(0,'Attendere...'); %Waitbar per UI
            pause(0.3) %Waitbar per UI
            
                for i = 1 : size(data,1)
                    waitbar(i/wait_length, waitbar_2, 'Inserendo i dati nel database'); %Waitbar per UI
                    
                    % Query 1
                    params_query_1 = {string(data{i,1}.case_id), string(data{i,1}.disease_type), string(data{i,1}.primary_site)};
                    prepStat_1 = databasePreparedStatement(app.connection, sqlQuery1);
                    prepStat_1 = bindParamValues(prepStat_1, [1:3], params_query_1);
                    execute(app.connection, prepStat_1);
                    
                    % Query 2
                    if isfield(data{i,1}, 'demographic')
                        params_query_2 = {};
                        if isfield(data{i,1}.demographic, 'demographic_id')
                            params_query_2{1} = string(data{i,1}.demographic.demographic_id);
                        end
                        if isfield(data{i,1}.demographic, 'race')
                            params_query_2{2} = string(data{i,1}.demographic.race);
                        end
                        if isfield(data{i,1}.demographic, 'days_to_birth')
                            params_query_2{3} = string(data{i,1}.demographic.days_to_birth);
                        end
                        if isfield(data{i,1}.demographic, 'ethnicity')
                            params_query_2{4} = string(data{i,1}.demographic.ethnicity);
                        end
                        if isfield(data{i,1}.demographic, 'year_of_birth')
                            params_query_2{5} = string(data{i,1}.demographic.year_of_birth);
                        end
                        if isfield(data{i,1}.demographic, 'vital_status')
                            params_query_2{6} = string(data{i,1}.demographic.vital_status);
                        end
                        if isfield(data{i,1}.demographic, 'age_at_index')
                            params_query_2{7} = string(data{i,1}.demographic.age_at_index);
                        end
                        if isfield(data{i,1}.demographic, 'year_of_death') 
                            params_query_2{8} = string(data{i,1}.demographic.year_of_death);
                        end
                        if isfield(data{i,1}.demographic, 'gender')
                            params_query_2{9} = string(data{i,1}.demographic.gender);
                        end
                        params_query_2{10} = string(data{i,1}.case_id);
                        
                        params_query_2 = app.fix_missing_params(params_query_2); %Inserimento di "missing" per tutti i valori nulli (le stringhe vuote non vengono gestite, dà errore)
                        
                        prepStat_2 = databasePreparedStatement(app.connection, sqlQuery2);
                        prepStat_2 = bindParamValues(prepStat_2, [1:10], params_query_2);
                        execute(app.connection, prepStat_2);
                    end
                    
                    % Query 3
                    if isfield(data{i,1}, 'exposures')
                        if size(data{i}.exposures, 1) == 1 %Non è supportato il brace indexing quando c'è solo un elemento, quindi lo inseriamo in un cell per poter iterare comunque
                            data{i}.exposures = {data{i}.exposures};
                        end
                        for j = 1:size(data{i}.exposures, 1)
                            params_query_3 = {};
                            if isfield(data{i,1}.exposures{j}, 'exposure_id')
                                params_query_3{1} = string(data{i,1}.exposures{j}.exposure_id);
                            end
                            if isfield(data{i,1}.exposures{j}, 'height')
                                params_query_3{2} = string(data{i,1}.exposures{j}.height);
                            end
                            if isfield(data{i,1}.exposures{j}, 'weight')
                                params_query_3{3} = string(data{i,1}.exposures{j}.weight);
                            end
                            if isfield(data{i,1}.exposures{j}, 'alcohol_history')
                                params_query_3{4} = string(data{i,1}.exposures{j}.alcohol_history);
                            end
                            if isfield(data{i,1}.exposures{j}, 'cigarettes_per_day')
                                params_query_3{5} = string(data{i,1}.exposures{j}.cigarettes_per_day);
                            end
                            if isfield(data{i,1}.exposures{j}, 'years_smoked')
                                params_query_3{6} = string(data{i,1}.exposures{j}.years_smoked);
                            end
                            if isfield(data{i,1}.exposures{j}, 'bmi')
                                params_query_3{7} = string(data{i,1}.exposures{j}.bmi);
                            end
                            if isfield(data{i,1}.exposures{j}, 'alcohol_intensity')
                                params_query_3{8} = string(data{i,1}.exposures{j}.alcohol_intensity);
                            end
                            params_query_3{9} = string(data{i,1}.case_id);
                            
                            params_query_3 = app.fix_missing_params(params_query_3);
                            
                            prepStat_3 = databasePreparedStatement(app.connection, sqlQuery3);
                            prepStat_3 = bindParamValues(prepStat_3, [1:9], params_query_3);
                            execute(app.connection, prepStat_3);
                        end
                        
                    end
                    
                    % Query 4
                    if isfield(data{i,1}, 'diagnoses')
                        if size(data{i}.diagnoses, 1) == 1 %Non è supportato il brace indexing quando c'è solo un elemento, quindi lo inseriamo in un cell per poter iterare comunque
                            data{i}.diagnoses = {data{i}.diagnoses};
                        end
                        for j = 1 : size(data{i}.diagnoses, 1)
                            params_query_4 = {};
                            if isfield(data{i,1}.diagnoses{j}, 'diagnosis_id')
                                params_query_4{1} = string(data{i,1}.diagnoses{j}.diagnosis_id);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'site_of_resection_or_biopsy')
                                params_query_4{2} = string(data{i,1}.diagnoses{j}.site_of_resection_or_biopsy);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'prior_treatment')
                                params_query_4{3} = string(data{i,1}.diagnoses{j}.prior_treatment);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'progression_or_recurrence')
                                params_query_4{4} = string(data{i,1}.diagnoses{j}.progression_or_recurrence);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'age_at_diagnosis')
                                params_query_4{5} = string(data{i,1}.diagnoses{j}.age_at_diagnosis);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'ajcc_staging_system_edition')
                                params_query_4{6} = string(data{i,1}.diagnoses{j}.ajcc_staging_system_edition);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'days_to_last_known_disease_status')
                                params_query_4{7} = string(data{i,1}.diagnoses{j}.days_to_last_known_disease_status);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'ajcc_pathologic_t')
                                params_query_4{8} = string(data{i,1}.diagnoses{j}.ajcc_pathologic_t);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'days_to_diagnosis')
                                params_query_4{9} = string(data{i,1}.diagnoses{j}.days_to_diagnosis);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'days_to_recurrence')
                                params_query_4{10} = string(data{i,1}.diagnoses{j}.days_to_recurrence);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'last_known_disease_status')
                                params_query_4{11} = string(data{i,1}.diagnoses{j}.last_known_disease_status);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'ajcc_pathologic_n')
                                params_query_4{12} = string(data{i,1}.diagnoses{j}.ajcc_pathologic_n);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'icd_10_code')
                                params_query_4{13} = string(data{i,1}.diagnoses{j}.icd_10_code);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'tissue_or_organ_of_origin')
                                params_query_4{14} = string(data{i,1}.diagnoses{j}.tissue_or_organ_of_origin);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'ajcc_pathologic_stage')
                                params_query_4{15} = string(data{i,1}.diagnoses{j}.ajcc_pathologic_stage);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'tumor_stage')
                                params_query_4{16} = string(data{i,1}.diagnoses{j}.tumor_stage);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'tumor_grade')
                                params_query_4{17} = string(data{i,1}.diagnoses{j}.tumor_grade);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'year_of_diagnosis')
                                params_query_4{18} = string(data{i,1}.diagnoses{j}.year_of_diagnosis);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'morphology')
                                params_query_4{19} = string(data{i,1}.diagnoses{j}.morphology);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'days_to_last_follow_up')
                                params_query_4{20} = string(data{i,1}.diagnoses{j}.days_to_last_follow_up);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'classification_of_tumor')
                                params_query_4{21} = string(data{i,1}.diagnoses{j}.classification_of_tumor);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'synchronous_malignancy')
                                params_query_4{22} = string(data{i,1}.diagnoses{j}.synchronous_malignancy);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'ajcc_pathologic_m')
                                params_query_4{23} = string(data{i,1}.diagnoses{j}.ajcc_pathologic_m);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'prior_malignancy')
                                params_query_4{24} = string(data{i,1}.diagnoses{j}.prior_malignancy);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'state')
                                params_query_4{25} = string(data{i,1}.diagnoses{j}.state);
                            end
                            if isfield(data{i,1}.diagnoses{j}, 'primary_diagnosis')
                                params_query_4{26} = string(data{i,1}.diagnoses{j}.primary_diagnosis);
                            end
                            params_query_4{27} = string(data{i,1}.case_id);
                            
                            params_query_4 = app.fix_missing_params(params_query_4);
                            
                            prepStat_4 = databasePreparedStatement(app.connection, sqlQuery4);
                            prepStat_4 = bindParamValues(prepStat_4, [1:27], params_query_4);
                            execute(app.connection, prepStat_4);
                            
                            % Query 5 (annidata nella 4: treatments è dentro
                            % diagnoses)
                            if isfield(data{i,1}.diagnoses{j}, 'treatments')
                                for k = 1:size(data{i,1}.diagnoses{j}.treatments, 1)
                                    params_query_5 = {};
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'treatment_id')
                                        params_query_5{1} = string(data{i,1}.diagnoses{j}.treatments{k}.treatment_id);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'treatment_intent_type')
                                        params_query_5{2} = string(data{i,1}.diagnoses{j}.treatments{k}.treatment_intent_type);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'treatment_or_therapy')
                                        params_query_5{3} = string(data{i,1}.diagnoses{j}.treatments{k}.treatment_or_therapy);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'treatment_type')
                                        params_query_5{4} = string(data{i,1}.diagnoses{j}.treatments{k}.treatment_type);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'therapeutic_agents')
                                        params_query_5{5} = string(data{i,1}.diagnoses{j}.treatments{k}.therapeutic_agents);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'treatment_effect')
                                        params_query_5{6} = string(data{i,1}.diagnoses{j}.treatments{k}.treatment_effect);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'treatment_outcome')
                                        params_query_5{7} = string(data{i,1}.diagnoses{j}.treatments{k}.treatment_outcome);
                                    end
                                    if isfield(data{i,1}.diagnoses{j}.treatments{k}, 'initial_disease_status')
                                        params_query_5{8} = string(data{i,1}.diagnoses{j}.treatments{k}.initial_disease_status);
                                    end
                                    params_query_5{9} = string(data{i,1}.diagnoses{j}.diagnosis_id);
                                    
                                    params_query_5 = app.fix_missing_params(params_query_5);
                                    
                                    prepStat_5 = databasePreparedStatement(app.connection, sqlQuery5);
                                    prepStat_5 = bindParamValues(prepStat_5, [1:9], params_query_5);
                                    execute(app.connection, prepStat_5);
                                end
                            end
                        end
                    end
                    
                
                end
                waitbar(1, waitbar_2, 'Procedura completata'); %Waitbar per UI
                pause(0.5) %Waitbar per UI
                close(waitbar_2) %Waitbar per UI
                app.ScaricaRNASeqdeicasiButton.Enable = 'on';
                app.ScaricaRNASeqdeicasiButton.BackgroundColor = [.72 .91 .58];
                app.InseriscidatideicasineldatabaseButton.BackgroundColor = [.96 .96 .96]; %Reimportazione colore di default (grigio)
                uialert(app.ClassificatoreperGDCDataUIFigure, 'Casi inseriti correttamente nel database','Operazione completata correttamente','Icon','success');
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
                close(waitbar_2)
            end
        end

        % Button pushed function: ScaricaRNASeqdeicasiButton
        function ScaricaRNASeqdeicasiButtonPushed(app, event)
            try
                waitbar_3 = waitbar(0,'Attendere...'); %Waitbar per UI
                pause(0.3) %Waitbar per UI
                
                %Recupero ID dei casi dal database e inietto nel JSON
                HTTP_body = jsondecode(app.JSONrequestRNASeq);
                table_name = 'demographic';
                sqlQuery = ['SELECT * FROM ' table_name];
               
                [IDs] = fetch(app.connection, sqlQuery);
                waitbar(.20, waitbar_3, 'ID Recuperati con successo'); %Waitbar per UI
                
                HTTP_body.filters.content(1).content.value = IDs.case_id; % Qui viene creata la lista vera dei case_id e inserita nella richiesta
                
                % Richiesta HTTP
                method = matlab.net.http.RequestMethod.POST;
                contentTypeField = matlab.net.http.field.ContentTypeField('application/json');
                header = [contentTypeField];
                body = matlab.net.http.MessageBody(HTTP_body);
                
                waitbar(.33, waitbar_3, 'Formulo la richiesta'); %Waitbar per UI
                
                destination = "https://api.gdc.cancer.gov/files/";
                request = matlab.net.http.RequestMessage(method, header, body);
                uri = matlab.net.URI(destination);
                
                waitbar(.67, waitbar_3, 'Scaricando dati dal GDC Data Portal'); %Waitbar per UI
                
                %https://it.mathworks.com/help/matlab/ref/matlab.net.http.requestmessage.send.html
                [app.response_seq] = send(request,uri);
                if app.response_seq.StatusCode == 200
                    waitbar(1, waitbar_3, 'Procedura terminata con successo'); %Waitbar per UI
                    close(waitbar_3) %Waitbar per UI
                else 
                    waitbar(1, waitbar_3, 'Procedura non completata. Chiusura in corso'); %Waitbar per UI
                    pause(1.4) %Waitbar per UI
                    close(waitbar_3) %Waitbar per UI
                end
                
                mkdir RNASeq_files %Creazione della cartella dove scaricare i file
                path = [pwd '\' 'RNASeq_files' '\']; %Variabile dove viene conservato il luogo dove scaricare i file
                addpath([pwd '\RNASeq_files\']) %Se non è un path in matlab viene aggiunto

                waitbar_4 = waitbar(0,'Scaricamento degli RNA-Seq...'); %Waitbar per UI
                pause(1) %Waitbar per UI
                
                seq_size = size(app.response_seq.Body.Data.data.hits, 1);                
                for i = 1 : seq_size
                    file_id = app.response_seq.Body.Data.data.hits(i).file_id;
                    filename = app.response_seq.Body.Data.data.hits(i).file_name;
                    
                    fullURL = ['https://api.gdc.cancer.gov/data/' file_id];
                    
                    if isfile(strcat(path, filename(1:end-3))) == 0 %Controlla se i file sono stati già scaricati precedentemente
                        websave(strcat(path, filename), fullURL);
                        gunzip(strcat(path, filename))
                        delete (strcat(path, filename))
                    end
                
                    waitbar(i/seq_size, waitbar_4, sprintf('Scaricamento degli RNA-Seq...: %d/%d',i,seq_size)); %Waitbar per UI
                end
                close(waitbar_4)
                app.InserisciRNASeqneldatabaseButton.Enable = 'on';
                app.InserisciRNASeqneldatabaseButton.BackgroundColor = [.72 .91 .58];
                app.ScaricaRNASeqdeicasiButton.BackgroundColor = [.94 .94 .94]; %Reimportazione colore di default (grigio)
                uialert(app.ClassificatoreperGDCDataUIFigure, 'RNA-Seq scaricati correttamente','Operazione completata correttamente','Icon','success');
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: InserisciRNASeqneldatabaseButton
        function InserisciRNASeqneldatabaseButtonPushed(app, event)
            try
                waitbar_6 = waitbar(0,'Attendere inizio estrazione...'); %Waitbar per UI
                pause(0.3) %Waitbar per UI
                
                % Estrazione espressione genica dai file (possono risultare di meno in quanto non tutti i casi hanno sequenziamenti effettuati. Alcuni ne presentano anche 2 e vengono filtrati)
                RNA_Seq_table = readtable(app.response_seq.Body.Data.data.hits(1).file_name(1:end-3),'FileType', 'text', 'ReadRowNames', 1); %Features su righe
                RNA_Seq_table.Properties.VariableNames{'Var1'} = app.response_seq.Body.Data.data.hits(1).cases.case_id;
                files_number = size(app.response_seq.Body.Data.data.hits, 1);
                for k = 2 : files_number
                    temp_file_name = app.response_seq.Body.Data.data.hits(k).file_name(1:end-3);
                    a = readtable(temp_file_name,'FileType', 'text', 'ReadRowNames', 1);
                    a.Properties.VariableNames{'Var1'} = app.response_seq.Body.Data.data.hits(k).cases.case_id;
                    if ismember(a.Properties.VariableNames, RNA_Seq_table.Properties.VariableNames) == 0 %Inserisce il sequenziamento solo se quel caso non ne ha ancora uno. Questo perchè possono esserci sequenziamenti di più zone all'interno dello stesso caso
                        RNA_Seq_table = [RNA_Seq_table, a];
                    end
                    waitbar(k/files_number, waitbar_6, sprintf('File processati: %d/%d',k,files_number)); %Waitbar per UI
                end
                close(waitbar_6)
                
                % Rimozione gene id
                row_names = RNA_Seq_table.Properties.RowNames;
                
                for h = 1 : numel(row_names)
                    splitted_strings = strsplit(row_names{h},'.');
                    row_names{h} = splitted_strings{1};
                end
                RNA_Seq_table.Properties.RowNames = row_names;
                
                % Verifica del numero di geni da conservare
                sqlQuery_1 = ['select column_name from information_schema.columns where table_name="genes_expression"']; 
                gene_expression_columns = fetch(app.connection,sqlQuery_1);
                gene_expression_columns = table2array(gene_expression_columns(2:end,"COLUMN_NAME")); %Viene prima sottratto il case_id dal numero di colonne presenti nella tabella e poi convertito in tanti cell array per poter essere accettati dal comando table per filtrare la tabella ottenendo solo l'espressione genica dei geni d'interesse caso per caso 
                
                % Riduzione tabella ai soli geni d'interesse
                RNA_Seq_table_reduced = RNA_Seq_table(gene_expression_columns,1:end); %Rimozione di tutti i geni non d'interesse (non tumor census)
                
                % Creazione query per inserire il corretto numero di geni
                % per paziente
                table_name = 'genes_expression';
                sqlQuery_2 = ['INSERT INTO ' table_name ' VALUES('];
                sqlQuery_2 = [sqlQuery_2 repmat('?, ', 1, size(gene_expression_columns, 1)+1)]; %Viene aggiunto il case_id al numero di colonne presenti nella tabella
                sqlQuery_2 = [sqlQuery_2(1:end-2) ')'];
                
                waitbar_7 = waitbar(0,'Attendere inizio inserimento dati...'); %Waitbar per UI
                pause(0.3) %Waitbar per UI
                cases_number = size(RNA_Seq_table_reduced, 2);
                for s = 1:cases_number %Itera per ogni case_id (sono sulle colonne)
                    params_query_2 = {};    
                    params_query_2{1} = string(RNA_Seq_table_reduced.Properties.VariableNames(s)); %Aggiunta del case_id
                    for m = 1:size(RNA_Seq_table_reduced, 1) %Itera per ogni gene da conservare (sulle righe)
                        params_query_2{m + 1} = string(RNA_Seq_table_reduced{m,s}); %Prende il valore corrispondente e lo trasforma in stringa, perchè così è accettato dal Database explorer
                    end
                    prepStat_2 = databasePreparedStatement(app.connection, sqlQuery_2);
                    prepStat_2.ParameterValues = params_query_2; %Sostituito il comando bind per performance (più di 30 secondi per ogni query)

%                    prepStat_2 = bindParamValues(prepStat_2, [1:size(gene_expression_columns, 1)+1], params_query_2);
                    execute(app.connection, prepStat_2);
                    waitbar(s/cases_number, waitbar_7, sprintf('RNA-Seq inseriti: %d/%d',s,cases_number)); %Waitbar per UI
                end
                close(waitbar_7)
                app.InserisciRNASeqneldatabaseButton.BackgroundColor = [.96 .96 .96];
                uialert(app.ClassificatoreperGDCDataUIFigure, 'RNA-Seq inseriti correttamente nel database','Operazione completata correttamente','Icon','success');
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: CreaTabellageniButton
        function CreaTabellageniButtonPushed(app, event)
            try
                % Scaricamento lista geni
                waitbar_5 = waitbar(0,'Attendere...'); %Waitbar per UI
                pause(0.3) %Waitbar per UI
                
                % Richiesta HTTP
                method = matlab.net.http.RequestMethod.POST;
                contentTypeField = matlab.net.http.field.ContentTypeField('application/json');
                header = [contentTypeField];
                body = matlab.net.http.MessageBody(jsondecode(app.JSONrequestGeneList));
                
                waitbar(.33, waitbar_5, 'Formulo la richiesta'); %Waitbar per UI
                
                destination = "https://api.gdc.cancer.gov/genes/";
                request = matlab.net.http.RequestMessage(method, header, body);
                uri = matlab.net.URI(destination);
                
                waitbar(.67, waitbar_5, 'Scaricando i geni dal GDC Data Portal'); %Waitbar per UI
                
                %https://it.mathworks.com/help/matlab/ref/matlab.net.http.requestmessage.send.html
                [app.response_genes] = send(request,uri);
                if app.response_genes.StatusCode == 200
                    waitbar(1, waitbar_5, 'Procedura terminata con successo'); %Waitbar per UI
                    pause(1) %Waitbar per UI
                    close(waitbar_5) %Waitbar per UI
                else 
                    waitbar(1, waitbar_5, 'Procedura non completata. Chiusura in corso'); %Waitbar per UI
                    pause(1.4) %Waitbar per UI
                    close(waitbar_5) %Waitbar per UI
                end
                
                % Sorting dei Geni (necessario perchè il recupero dei geni dal database viene ordinato automaticamente [anche se non lo sono] e la query ha bisogno di un ordine prestabilito)
                sorted_genes = {app.response_genes.Body.Data.data.hits.gene_id};
                sorted_genes = sort(sorted_genes');
                
                % Creazione ed esecuzione Query
                sqlquery_1 = ['create table if not exists genes_expression ( case_id varchar(100) primary key, '];
                for i = 1:size(sorted_genes, 1) % Ciclo per immettere tutti i geni tumorali recuperati dal GDC come colonne
                    sqlquery_1 = [sqlquery_1 sorted_genes{i} ' int, '];
                end
                sqlquery_1 = [sqlquery_1(1:end-2) ')']; %Togliamo prima la virgola dell'ultimo elemento, altrimenti MySQL dà errore di sintassi: "xxxx,)" 
                execute(app.connection,sqlquery_1);
                
                uialert(app.ClassificatoreperGDCDataUIFigure, 'Tabella creata correttamente','Operazione completata correttamente','Icon','success');   
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure, ME.message, 'Errore');
            end
        end

        % Button pushed function: CancellaTabellageniButton
        function CancellaTabellageniButtonPushed(app, event)
            try
                sqlquery_drop = ['drop table genes_expression'];
                execute(app.connection,sqlquery_drop);
                uialert(app.ClassificatoreperGDCDataUIFigure,'Tabella eliminata con successo','Operazione completata correttamente','Icon','success');
            catch ME
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: RecuperainformazioniprogettoButton
        function RecuperainformazioniprogettoButtonPushed(app, event)
            % Verifica, per successiva selezione, di quanti tipi di
            % malattie è composto il progetto
            query_1 = ['SELECT DISTINCT disease_type FROM subjects;'];
            [disease_types] = fetch(app.connection, query_1);
            
            app.MalattieListBox.Items = disease_types.disease_type';
            app.ScaricaredatidellaselezioneButton.Enable = 'on';
            app.TargettizzazionedatasetgravenongraveButton.Enable = 'on';
        end

        % Button pushed function: ScaricaredatidellaselezioneButton
        function ScaricaredatidellaselezioneButtonPushed(app, event)
             % Query per selezionare solo i casi di interesse (case_id con
             % uno specifico disease_type) e la classificazione TNM
             try
             query_analysis_table_full = [app.query_analysis_table_1 '"' app.MalattieListBox.Value '"' app.query_analysis_table_2];
             [app.analysis_table] = fetch(app.connection, query_analysis_table_full);
             
             
             % Sono abilitati in questo punto in quanto è qui che si hanno
             % gli strumenti minimi per eseguire queste funzioni, nonostante
             % è consigliato seguire i passaggi successivi
             app.tDistributedStochasticNeighborEmbeddingPanel.Enable = 'on';
             
             app.PrincipalComponentAnalysisPanel.Enable = 'on';
             
             
             table_view_selection = uiconfirm(app.ClassificatoreperGDCDataUIFigure,'Vuoi vedere la tabella?','Visonare tabella','Options',{'Si','No'});
             switch table_view_selection
                 case 'Si'
                     fig_table = uifigure;
                     fig_table.WindowState = 'maximized';
                     uitable(fig_table,'Data',app.analysis_table, 'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                 case 'No'
                     return
             end

             catch ME
                 uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
             end
        end

        % Button pushed function: 
        % TargettizzazionedatasetgravenongraveButton
        function TargettizzazionedatasetgravenongraveButtonPushed(app, event)
            % Il dataset viene diviso considerando il parametro T della
            % classificazione TNM
            %
            % Stadio non grave: [T1, T1a, T1b, T2, T2a, T2b]
            % Stadio grave: [T3, T4]
            try
                % Casi gravi
                severe_cases = app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T3'), :);
                severe_cases = [severe_cases; app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T4'), :)];
                % Aggiunta target come ultima colonna
                severity_column_1 = array2table(repmat(1,size(severe_cases, 1),1),'VariableNames',{'severity'});
                severe_cases = [severe_cases severity_column_1];
                app.severe_cases_full = severe_cases(:,1:end-1);
                
                % Casi lievi
                minor_cases = app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T1'), :);
                minor_cases = [minor_cases; app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T1a'), :)];
                minor_cases = [minor_cases; app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T1b'), :)];
                minor_cases = [minor_cases; app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T2'), :)];
                minor_cases = [minor_cases; app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T2a'), :)];
                minor_cases = [minor_cases; app.analysis_table(strcmp(app.analysis_table.ajcc_pathologic_t,'T2b'), :)];
                % Aggiunta target come ultima colonna
                severity_column_2 = array2table(repmat(0,size(minor_cases, 1),1),'VariableNames',{'severity'});
                minor_cases = [minor_cases severity_column_2];
                app.minor_cases_full = minor_cases(:,1:end-1);
                
                % Composizione tabella finale per split
                app.total_cases = [severe_cases; minor_cases];
 
                % Randomizzazione ordine delle righe
                total_cases_randorder = app.total_cases(randperm(size(app.total_cases,1)),:);
                app.total_cases_randorder_array = table2array(total_cases_randorder(:,7:end));
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('Dati divisi e targettizzati correttamente\nCasi gravi: %d\nCasi lievi: %d', size(severe_cases,1), size(minor_cases,1)), 'Successo','Icon','success');
                
                % Verifica rateo casi gravi/non-gravi
                % Alcuni riportano un rateo > di 1:10 per essere considerato
                % sbilanciato, ma non ho trovato molte fonti (quindi lo do a cazzo)
                
                rateo_cases = size(severe_cases, 1)/size(minor_cases, 1);
                if rateo_cases < 0.3 || rateo_cases> 3
                    uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('Attenzione, rateo dei casi: %.2f\nSi consiglia di effettuare un over- o undersampling', rateo_cases), 'Attenzione','Icon','warning');
                end
                
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: OversamplingButton
        function OversamplingButtonPushed(app, event)
            try
                [app.x_train, app.t_train] = SMOTE(app.x_train, app.t_train);
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('Dati sintetizzati correttamente\nCasi gravi: %d\nCasi lievi: %d',sum(app.t_train), size(app.x_train,1) - sum(app.t_train)), 'Successo','Icon','success');
                
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: NormalizzadatiButton
        function NormalizzadatiButtonPushed(app, event)
            try
                                    
            [xNorm, mu, sigma] = zscore(app.total_cases_randorder_array(:,1:end-1));
            app.total_cases_randorder_array(:,1:end-1) = xNorm;
           
            % Alert
            uialert(app.ClassificatoreperGDCDataUIFigure, 'Procedura effettuata correttamente', 'Successo','Icon','success'); 
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Value changing function: TrainSlider
        function TrainSliderValueChanging(app, event)
            app.TestSlider.Value = 100 - event.Value; 
        end

        % Button pushed function: DivididatasetButton
        function DivididatasetButtonPushed(app, event)
            try
            % Divisione dataset in train e test con i valori degli slider
            [train_data,~,test_data] = dividerand(app.total_cases_randorder_array', app.TrainSlider.Value, 0, app.TestSlider.Value); % L'array viene trasposto perchè divide le colonne, che quindi devono essere i casi (le righe sono le features)
            
            % Divisione dei dati tra input e output (target)
            % (Reimposto feature su colonne e casi su righe)
            app.x_train = train_data(1:end-1,:)';
            app.t_train = train_data(end,:)';
            app.x_test = test_data(1:end-1,:)';
            app.t_test = test_data(end,:)';
            
            
            % Alert
            uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('Dati divisi correttamente\nTrain set: %d casi\nTest set: %d casi', size(app.x_train, 1), size(app.x_test, 1)), 'Successo','Icon','success'); 
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Value changing function: TestSlider
        function TestSliderValueChanging(app, event)
            app.TrainSlider.Value = 100 - event.Value;
        end

        % Button pushed function: EseguiPCAButton
        function EseguiPCAButtonPushed(app, event)
            try
                app.tDistributedStochasticNeighborEmbeddingPanel.Enable = 'off';
                
                % Si presuppone che la matrice sia stata già normalizzata con
                % il tasto alla fase precedente
                warning('off')
                [coeff_pca,~,~,~,explained_pca] = pca(app.x_train,'Centered',false);
                warning('on')
                
                % Riduzione della dimensionalità grazie ai coefficienti forniti
                % dalla PCA
                app.x_train = app.x_train * coeff_pca;
 
                n_comp = numel(explained_pca);
                for ii = 1:n_comp
                    % Calcolo la somma dei primi "ii" elementi di explained_pca
                    sum_ = sum(explained_pca(1:ii));
                    if sum_ > app.VarianzadapreservareSpinner.Value, break; end
                end
                num_princ_comp = ii;
                
                app.x_train = app.x_train(:,1:num_princ_comp);
                
                % PCA sul test
                app.x_test = app.x_test * coeff_pca;
                app.x_test = app.x_test(:,1:num_princ_comp);
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('PCA eseguita correttamente\nPer preservare il %d %% della varianza\nsono state selezionate %d componenti', app.VarianzadapreservareSpinner.Value, num_princ_comp), 'Successo','Icon','success'); 
     
                % Grafico varianza spiegata
                gaph_view_pca_selection = uiconfirm(app.ClassificatoreperGDCDataUIFigure,'Vuoi vedere il grafico della varianza?','Visonare varianza dopo PCA','Options',{'Si','No'});
                switch gaph_view_pca_selection
                    case 'Si'
                        fig_2 = uifigure; % Creo la figura (finestra)
                        plot_var_expl = uiaxes(fig_2); % Creo un plot bianco da inserire dentro la figura
                        title(plot_var_expl,'Varianza delle prime componenti dopo PCA') 
                        ylabel(plot_var_expl,'Proporzione della varianza (%)')
                        xlabel(plot_var_expl,'Componenti')
                        plot_var_expl.Position = [plot_var_expl.Position(1:2) [fig_2.Position(3:4) - 2*plot_var_expl.Position(1:2)]]; % Fit del plot a tutta la figura
                        bar(plot_var_expl,explained_pca(1:num_princ_comp+10)); %Bar plot delle prime componenti principali (non si vedono bene tutte)
                        
                    case 'No'
                        %Va avanti
                end
                plot_view_selection = uiconfirm(app.ClassificatoreperGDCDataUIFigure,'Vuoi visionare i grafici 2D delle prime 2 componenti ?','Visonare grafici','Options',{'Si','No'});
                switch plot_view_selection
                    case 'Si'
                        fig_plot_pca = uifigure;
                        fig_plot_ax_pca = uiaxes(fig_plot_pca);
                        title(fig_plot_ax_pca,'Punti nello spazio 2D dopo PCA: Comp. 1 e 2')
                        xlabel(fig_plot_ax_pca,'PC 1')
                        ylabel(fig_plot_ax_pca,'PC 2')                        
                        fig_plot_ax_pca.Position = [fig_plot_ax_pca.Position(1:2) [fig_plot_pca.Position(3:4) - 2*fig_plot_ax_pca.Position(1:2)]]; % Fit del plot a tutta la figura
                        gscatter(fig_plot_ax_pca, app.x_train(:,1), app.x_train(:,2), app.t_train, "rb");
                    case 'No'
                        %Va avanti
                end
                
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: EseguitSNEButton
        function EseguitSNEButtonPushed(app, event)
            try
                % t-SNE non viene solitamente usato per problemi di
                % classificazione ma più per la visualizzazione delle
                % features su un piano 2D o al più 3D.
                
                app.PrincipalComponentAnalysisPanel.Enable = 'off';
                
                % Perplexity: effettivo numero di "vicini" che rappresentano il
                % punto nel nuovo spazio creato da t-SNE
                %
                %Features sulle colonne
                app.x_train = tsne(app.x_train, 'NumDimension', app.NumerodifeaturesdapreservareSpinner.Value, 'Perplexity', app.PerplexitySpinner.Value);
                if size(app.x_train, 2) == 2
                    gaph_view_tsne_selection = uiconfirm(app.ClassificatoreperGDCDataUIFigure,'Vuoi vedere il grafico 2D dei punti?','Visonare grafico punti t-SNE','Options',{'Si','No'});
                    switch gaph_view_tsne_selection
                        case 'Si'
                            fig_3 = uifigure; % Creo la figura (finestra)
                            plot_tsne_points = uiaxes(fig_3); % Creo un plot bianco da inserire dentro la figura
                            title(plot_tsne_points,'Punti nello spazio 2D dopo t-SNE')
                            plot_tsne_points.Position = [plot_tsne_points.Position(1:2) [fig_3.Position(3:4) - 2*plot_tsne_points.Position(1:2)]]; % Fit del plot a tutta la figura
                            % Scatter plot che permette di visualizzare i punti
                            % conoscendo anche il target (distinti per colori)
                            gscatter(plot_tsne_points, app.x_train(:,1), app.x_train(:,2), app.t_train, "rg")
                        case 'No'
                           %Va avanti
                    end
                end
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, 't-SNE terminato con successo', 'Successo','Icon','success');
                
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Value changed function: CorrezionepValueCheckBox
        function CorrezionepValueCheckBoxValueChanged(app, event)
            if app.CorrezionepValueCheckBox.Value
                app.MetododicorrezioneDropDown.Enable = 'on';
            else
                app.MetododicorrezioneDropDown.Enable = 'off';
            end
        end

        % Button pushed function: EseguiTestIpotesiButton
        function EseguiTestIpotesiButtonPushed(app, event)
            try
                waitbar_6 = waitbar(0,'Attendere...'); %Waitbar per UI
                pause(0.3) %Waitbar per UI
                
                
                waitbar(.33, waitbar_6, 'Test delle ipotesi in corso...'); %Waitbar per UI
                
                % nbintest vuole features sulle righe
                x_array = table2array(app.severe_cases_full(:,7:end))';
                y_array = table2array(app.minor_cases_full(:,7:end))';
                
                % Test delle ipotesi assumendo una distribuzione binomiale
                % negativa
                %
                % Effettuato su due campioni indipendenti
                % Ipotesi nulla: X e Y provengono da distribuzioni con media uguale
                
                % VarianceLink esprime il rapporto che c'è tra media e
                % varianza
                % 'Constant': rapporto quadratico (varianza = media + k*media^2)
                % 'Identity': media = varianza
                tConstant = nbintest(x_array, y_array, 'VarianceLink','Constant');
                
                waitbar(.50, waitbar_6, 'Creazione tabella geni...'); %Waitbar per UI
                % Creazione tabella per valutazione geni (viene inserito solo il p-Value fin qui, 
                % le altre statistiche vengono aggiunte solo se calcolate)
                app.gene_table_val = table(tConstant.pValue, 'RowNames', app.severe_cases_full.Properties.VariableNames(7:end), 'VariableNames', {'p_Value'});
                
                figure, histogram(tConstant.pValue, 100)
                xlabel('p-Value')
                ylabel('Frequenza')
                title('p-Value Originali')
                
                % Alpha corretto: alpha impostato dall'utente / numero di features
                
                if app.CorrezionepValueCheckBox.Value
                    waitbar(.67, waitbar_6, 'Correzione p-Value...'); %Waitbar per UI
                    if app.MetododicorrezioneDropDown.Value == "Bonferroni"
                        %% FWER
                        %% Bonferroni
                        % Function 'fwer_bonf' computes the Bonferroni correction of Family-Wise
                        % Error Rate for multiple comparisons.
                        [c_pvalues, c_alpha, h] = fwer_bonf(tConstant.pValue, app.AlphaSpinner.Value);
                    elseif app.MetododicorrezioneDropDown.Value == "Sidak"
                        %% Sidak
                        % Function 'fwer_sidak' computes the Sidák correction of Family-Wise Error
                        % Rate for multiple comparisons.
                        [c_pvalues, c_alpha, h] = fwer_sidak(tConstant.pValue, app.AlphaSpinner.Value);
                    elseif app.MetododicorrezioneDropDown.Value == "Holm-Bonferroni"
                        %% Holm-Bonferroni
                        % Function 'fwer_holmbonf' computes the Holm-Bonferroni correction, also
                        % known as sequential Bonferroni correction, of Family-Wise Error Rate for
                        % multiple comparisons.
                        [c_pvalues, c_alpha, h] = fwer_holmbonf(tConstant.pValue, app.AlphaSpinner.Value);
                        c_pvalues = c_pvalues';
                        c_alpha = c_alpha';
                    end
                    app.gene_table_val.adjP_Value = c_pvalues;
                    app.gene_table_val.hyp_rejected = h;
                    
                    figure, histogram(c_pvalues, 100)
                    xlabel('p-Value')
                    ylabel('Frequenza')
                    title('p-Value Corretti')
                end
                
                if app.CalcolareFoldChangeCheckBox.Value
                    waitbar(.90, waitbar_6, 'Calcolo Fold Change...'); %Waitbar per UI
                    % Esprime il rateo di espressione tra le medie di due quantità
                    %
                    % Possiamo voler trovare quei geni che presentano una
                    % netta differenza di espressione tra casi severi e casi minori                   
                    meanHigh = mean(x_array, 2);
                    meanLow = mean(y_array, 2);
                    foldChange = meanHigh ./ meanLow;
                    log2FC = log2(foldChange);
                    app.gene_table_val.foldChange = foldChange;
                    app.gene_table_val.log2FC = log2FC;
                end
                
                waitbar(1, waitbar_6, 'Procedura terminata con successo'); %Waitbar per UI
                pause(1) %Waitbar per UI
                close(waitbar_6) %Waitbar per UI
                
                table_view_selection_stats = uiconfirm(app.ClassificatoreperGDCDataUIFigure,'Vuoi vedere la tabella con le statistiche?','Visonare tabella','Options',{'Si','No'});
                switch table_view_selection_stats
                    case 'Si'
                        fig_table_stats = uifigure;
                        fig_table_stats.WindowState = 'maximized';
                        app.table_stats_ui = uitable(fig_table_stats,'Data', app.gene_table_val, 'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                        app.table_stats_ui.ColumnSortable = true;
                        app.table_stats_ui.ColumnEditable = true;
                        
                        % Funzione che permette, tramite l'interazione dell'utente, di
                        % modificare la tabella dei geni con le statistiche
                        % (Potrebbe non essere il modo più efficiente di gestire
                        % questa cosa)
                        app.table_stats_ui.DisplayDataChangedFcn = createCallbackFcn(app, @updateTable, true);
                    case 'No'
                        % Va avanti
                end
                
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: ConservaButton
        function ConservaButtonPushed(app, event)
            try
                % Conservazione feature su train set
                
                table_train_full_train = array2table(app.x_train,"VariableNames",app.severe_cases_full.Properties.VariableNames(7:end));
                % app.gene_table_val ha le features ordinate in maniera diversa (sono sulle righe) 
                % rispetto all'ordinamento crescente, in base alla selezione dell'utente.
                % Quindi si selezionano le feature da conservare e si crea una tabella con solo le 
                % colonne desiderate
                table_train_selected = table_train_full_train(:,app.gene_table_val.Properties.RowNames(1:app.QuantefeatureconservareSpinner.Value));
                % Si converte nuovamente in matrice
                app.x_train = table2array(table_train_selected);
                
                % Conservazione feature su test set
                table_train_full_test = array2table(app.x_test,"VariableNames",app.severe_cases_full.Properties.VariableNames(7:end));
                table_test_selected = table_train_full_test(:,app.gene_table_val.Properties.RowNames(1:app.QuantefeatureconservareSpinner.Value));
                app.x_test = table2array(table_test_selected);
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, 'Procedura terminata con successo', 'Successo','Icon','success');
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
        end

        % Button pushed function: EseguiaddestramentoconGAButton
        function EseguiaddestramentoconGAButtonPushed(app, event)
            try 
                % Features sulle righe
                input_train = app.x_train'; 
                output_train = app.t_train';
                input_test = app.x_test';
                output_test = app.t_test';
                         
                display_iterations = 'off';
                if app.MostraIterazioniCheckBox.Value
                    display_iterations = 'iter';
                end
                
                ga_opts = gaoptimset('PopulationSize', app.PopolazioneSpinner.Value, 'TolFun', 1e-10, 'StallGenLimit', app.NumerodistallimaxSpinner.Value, 'FitnessLimit', app.LimiteFunzFitnessSpinner.Value,...
                'Generations', app.NumerodigenerezionimaxSpinner.Value, 'Display', display_iterations, 'EliteCount', app.IndividuiElitSpinner.Value);
                
                % Esecuzione algoritmo genetico
                [layersOptimized] = ga(@(hiddenLayerSize)ann(hiddenLayerSize, input_train, output_train,...
                    input_test, output_test), 3, [], [], [], [], [1 1 1], [6 6 4], [], [1 2 3], ga_opts);              
               
                trainFcn = 'traingdx';
                costFunction = 'crossentropy';
                epochs = 700;
                lastLayerActivationFunction = 'logsig';
                net = patternnet(layersOptimized, trainFcn);
                net.trainParam.epochs = epochs;
                net.performFcn = costFunction;
                net.layers{end}.transferFcn = lastLayerActivationFunction;
                net.divideFcn = 'dividerand';
                net.divideParam.trainRatio = 1;
                net.divideParam.valRatio   = 0;
                net.divideParam.testRatio  = 0;  
                net = configure(net,input_train, output_train);                
                net = init(net); 
                [net] = train(net,input_train, output_train);

                % Verifica dei risultati ottenuti
                output_nn_train = net(input_train);
                output_nn_test = net(input_test);
                
                figure, plotconfusion(output_train,output_nn_train),title('Train Confusion Matrix')
                figure, plotroc(output_train,output_nn_train)
      
                figure, plotconfusion(output_test,output_nn_test),title('Test Confusion Matrix')
                [X,Y,~,AUC_test] = perfcurve(output_test,output_nn_test,1);
                figure, plot(X,Y)
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('Procedura terminata con successo\nAUC sul Test: %d', AUC_test), 'Successo','Icon','success');
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore');
            end
            
            % Funzione da ottimizzare (ANN)
            function mse_test = ann(hiddenLayerSize, input_train, output_train, input_test, output_test)
                
                trainFcn = 'traingdx';
                costFunction = 'crossentropy';
                epochs = 700;
                lastLayerActivationFunction = 'logsig';
                
                net = patternnet(hiddenLayerSize, trainFcn);
                net.trainParam.epochs = epochs;
                net.performFcn = costFunction;
                net.layers{end}.transferFcn = lastLayerActivationFunction;
                
                net.divideFcn = 'dividerand';
                net.divideParam.trainRatio = 1;
                net.divideParam.valRatio   = 0;
                net.divideParam.testRatio  = 0;
                
                net = configure(net,input_train, output_train);
                
                net = init(net);
                
                [net] = train(net,input_train, output_train);
                               
                y_pred_test = net(input_test);
                
                mse_test = mse(y_pred_test, output_test);
            end
        end

        % Button pushed function: EseguiaddestramentosenzaGAButton
        function EseguiaddestramentosenzaGAButtonPushed(app, event)
            try
                input_train = app.x_train';
                output_train = app.t_train';
                input_test = app.x_test';
                output_test = app.t_test';
                
                hiddenLayerSize = [app.NeuroniLayer1Spinner.Value];
                if app.NeuroniLayer2Spinner.Value > 0 % La rete non gestisce un numero di layers pari a 0, ma al contempo si fornisce la possibilità di creare la rete con un solo strato di layer nascosti
                    hiddenLayerSize = [app.NeuroniLayer1Spinner.Value app.NeuroniLayer2Spinner.Value];
                end
                
                trainFcn = 'traingdx'; % traingda, traingdm, traingd
                
                net = patternnet(hiddenLayerSize, trainFcn);
                
                net.divideFcn = 'dividerand';
                net.divideParam.trainRatio = 1;
                net.divideParam.valRatio = 0;
                net.divideParam.testRatio = 0;
                
                % Criteri di Stop
                net.trainParam.epochs = app.IterazionimassimeSpinner.Value;
                net.trainParam.min_grad = 0.00001; %10e-5

                net.layers{end}.transferFcn = 'logsig';
                
                net.performFcn = 'crossentropy';
                % mae - Mean absolute error performance function.
                % mse - Mean squared error performance function.
                % sae - Sum absolute error performance function.
                % sse - Sum squared error performance function.
                % crossentropy - Cross-entropy performance.
                
                net = configure(net, input_train, output_train);
                
                warning('off')
                view(net); % Viene mostrato lo schema della rete neurale creata
                warning('on')

                net = init(net);
                [net] = train(net,input_train,output_train);
                
                % Verifica dei risultati ottenuti
                output_nn_train = net(input_train);
                output_nn_test = net(input_test);
                
                figure, plotconfusion(output_train,output_nn_train),title('Train Confusion Matrix')
                figure, plotroc(output_train,output_nn_train)
      
                figure, plotconfusion(output_test,output_nn_test),title('Test Confusion Matrix')
                [X,Y,~,AUC_test] = perfcurve(output_test,output_nn_test,1);
                figure, plot(X,Y)
                
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure, sprintf('Procedura terminata con successo\nAUC sul Test: %d', AUC_test), 'Successo','Icon','success');
            catch ME
                % Alert
                uialert(app.ClassificatoreperGDCDataUIFigure,ME.message, 'Errore'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassificatoreperGDCDataUIFigure = uifigure('Visible', 'off');
            app.ClassificatoreperGDCDataUIFigure.Name = 'Classificatore per GDC Data';
            app.TabGroup = uitabgroup(app.ClassificatoreperGDCDataUIFigure);
            app.ConnessioneTab = uitab(app.TabGroup);
            app.ConnessioneTab.Title = 'Connessione';
            app.ConnessioneServerMySQLPanel = uipanel(app.ConnessioneTab);
            app.ConnessioneServerMySQLPanel.Title = 'Connessione Server MySQL';
            app.ConnettiButton = uibutton(app.ConnessioneServerMySQLPanel, 'push');
            app.ConnettiButton.ButtonPushedFcn = createCallbackFcn(app, @ConnettiButtonPushed, true);
            app.ConnettiButton.Enable = 'off';
            app.ConnettiButton.Text = 'Connetti';
            app.UsernameEditFieldLabel = uilabel(app.ConnessioneServerMySQLPanel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.ConnessioneServerMySQLPanel, 'text');
            app.UsernameEditField.Editable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.ConnessioneServerMySQLPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.ConnessioneServerMySQLPanel, 'text');
            app.PasswordEditField.Editable = 'off';
            app.StatusLampLabel = uilabel(app.ConnessioneServerMySQLPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ConnessioneServerMySQLPanel);
            app.VerificastatusAPIGDCDataPortalPanel = uipanel(app.ConnessioneTab);
            app.VerificastatusAPIGDCDataPortalPanel.Title = 'Verifica status API GDC Data Portal';
            app.VerificaButton = uibutton(app.VerificastatusAPIGDCDataPortalPanel, 'push');
            app.VerificaButton.ButtonPushedFcn = createCallbackFcn(app, @VerificaButtonPushed, true);
            app.VerificaButton.Text = 'Verifica';
            app.StatusLamp_2Label = uilabel(app.VerificastatusAPIGDCDataPortalPanel);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.VerificastatusAPIGDCDataPortalPanel);
            app.VerificaDBeTabellePanel = uipanel(app.ConnessioneTab);
            app.VerificaDBeTabellePanel.Title = 'Verifica DB e Tabelle';
            app.CreaTabelleButton = uibutton(app.VerificaDBeTabellePanel, 'push');
            app.CreaTabelleButton.ButtonPushedFcn = createCallbackFcn(app, @CreaTabelleButtonPushed, true);
            app.CreaTabelleButton.Enable = 'off';
            app.CreaTabelleButton.Text = 'Crea Tabelle';
            app.CancellaTabelleButton = uibutton(app.VerificaDBeTabellePanel, 'push');
            app.CancellaTabelleButton.ButtonPushedFcn = createCallbackFcn(app, @CancellaTabelleButtonPushed, true);
            app.CancellaTabelleButton.Enable = 'off';
            app.CancellaTabelleButton.Text = 'Cancella Tabelle';
            app.CreaTabellageniButton = uibutton(app.VerificaDBeTabellePanel, 'push');
            app.CreaTabellageniButton.ButtonPushedFcn = createCallbackFcn(app, @CreaTabellageniButtonPushed, true);
            app.CreaTabellageniButton.Enable = 'off';
            app.CreaTabellageniButton.Text = 'Crea Tabella geni';
            app.CancellaTabellageniButton = uibutton(app.VerificaDBeTabellePanel, 'push');
            app.CancellaTabellageniButton.ButtonPushedFcn = createCallbackFcn(app, @CancellaTabellageniButtonPushed, true);
            app.CancellaTabellageniButton.Enable = 'off';
            app.CancellaTabellageniButton.Text = 'Cancella Tabella geni';
            app.VerificaDatabaseSourceinMatLABPanel = uipanel(app.ConnessioneTab);
            app.VerificaDatabaseSourceinMatLABPanel.Title = 'Verifica Database Source in MatLAB';
            app.OpzioniDropDownLabel = uilabel(app.VerificaDatabaseSourceinMatLABPanel);
            app.OpzioniDropDownLabel.Text = 'Opzioni';
            app.OpzioniDropDown = uidropdown(app.VerificaDatabaseSourceinMatLABPanel);
            app.OpzioniDropDown.Items = {'Verificare opzioni disponibili'};
            app.OpzioniDropDown.Value = 'Verificare opzioni disponibili';
            app.VerificaButton_3 = uibutton(app.VerificaDatabaseSourceinMatLABPanel, 'push');
            app.VerificaButton_3.ButtonPushedFcn = createCallbackFcn(app, @VerificaButton_3Pushed, true);
            app.VerificaButton_3.Text = 'Verifica';
            app.Label = uilabel(app.VerificaDatabaseSourceinMatLABPanel);
            app.Label.Text = '';
            app.DownloaddatiTab = uitab(app.TabGroup);
            app.DownloaddatiTab.Title = 'Download dati';
            app.StatusDownloadTextArea = uitextarea(app.DownloaddatiTab);
            app.VisualizzaJSONButton = uibutton(app.DownloaddatiTab, 'push');
            app.VisualizzaJSONButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizzaJSONButtonPushed, true);
            app.VisualizzaJSONButton.Text = 'Visualizza JSON';
            app.Label_2 = uilabel(app.DownloaddatiTab);
            app.Label_2.Text = '';
            app.SelezionareilprogettodellostudioDropDownLabel = uilabel(app.DownloaddatiTab);
            app.SelezionareilprogettodellostudioDropDownLabel.Text = 'Selezionare il progetto dello studio';
            app.SelezionareilprogettodellostudioDropDown = uidropdown(app.DownloaddatiTab);
            app.SelezionareilprogettodellostudioDropDown.Items = {'TCGA-LUAD', 'TCGA-UCEC'};
            app.SelezionareilprogettodellostudioDropDown.ValueChangedFcn = createCallbackFcn(app, @SelezionareilprogettodellostudioDropDownValueChanged, true);
            app.SelezionareilprogettodellostudioDropDown.Value = 'TCGA-LUAD';
            app.FasiperlapopolazionediMySQLPanel = uipanel(app.DownloaddatiTab);
            app.FasiperlapopolazionediMySQLPanel.Title = 'Fasi per la popolazione di MySQL';
            app.InseriscidatideicasineldatabaseButton = uibutton(app.FasiperlapopolazionediMySQLPanel, 'push');
            app.InseriscidatideicasineldatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @InseriscidatideicasineldatabaseButtonPushed, true);
            app.InseriscidatideicasineldatabaseButton.Enable = 'off';
            app.InseriscidatideicasineldatabaseButton.Text = {'Inserisci dati dei casi'; ' nel database'};
            app.ScaricacasiprogettoButton = uibutton(app.FasiperlapopolazionediMySQLPanel, 'push');
            app.ScaricacasiprogettoButton.ButtonPushedFcn = createCallbackFcn(app, @ScaricacasiprogettoButtonPushed, true);
            app.ScaricacasiprogettoButton.Enable = 'off';
            app.ScaricacasiprogettoButton.Text = 'Scarica casi progetto';
            app.ScaricaRNASeqdeicasiButton = uibutton(app.FasiperlapopolazionediMySQLPanel, 'push');
            app.ScaricaRNASeqdeicasiButton.ButtonPushedFcn = createCallbackFcn(app, @ScaricaRNASeqdeicasiButtonPushed, true);
            app.ScaricaRNASeqdeicasiButton.Enable = 'off';
            app.ScaricaRNASeqdeicasiButton.Text = 'Scarica RNA-Seq dei casi';
            app.InserisciRNASeqneldatabaseButton = uibutton(app.FasiperlapopolazionediMySQLPanel, 'push');
            app.InserisciRNASeqneldatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @InserisciRNASeqneldatabaseButtonPushed, true);
            app.InserisciRNASeqneldatabaseButton.Enable = 'off';
            app.InserisciRNASeqneldatabaseButton.Text = {'Inserisci RNA-Seq'; 'nel database'};
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.RecuperainformazioniprogettoButton = uibutton(app.PreprocessingTab, 'push');
            app.RecuperainformazioniprogettoButton.ButtonPushedFcn = createCallbackFcn(app, @RecuperainformazioniprogettoButtonPushed, true);
            app.RecuperainformazioniprogettoButton.Enable = 'off';
            app.RecuperainformazioniprogettoButton.Text = 'Recupera informazioni progetto';
            app.MalattieListBoxLabel = uilabel(app.PreprocessingTab);
            app.MalattieListBoxLabel.Text = 'Malattie';
            app.MalattieListBox = uilistbox(app.PreprocessingTab);
            app.MalattieListBox.Items = {'Recuperare informazioni'};
            app.MalattieListBox.Value = 'Recuperare informazioni';
            app.ScaricaredatidellaselezioneButton = uibutton(app.PreprocessingTab, 'push');
            app.ScaricaredatidellaselezioneButton.ButtonPushedFcn = createCallbackFcn(app, @ScaricaredatidellaselezioneButtonPushed, true);
            app.ScaricaredatidellaselezioneButton.Enable = 'off';
            app.ScaricaredatidellaselezioneButton.Text = 'Scaricare dati della selezione';
            app.TargettizzazionedatasetgravenongraveButton = uibutton(app.PreprocessingTab, 'push');
            app.TargettizzazionedatasetgravenongraveButton.ButtonPushedFcn = createCallbackFcn(app, @TargettizzazionedatasetgravenongraveButtonPushed, true);
            app.TargettizzazionedatasetgravenongraveButton.Enable = 'off';
            app.TargettizzazionedatasetgravenongraveButton.Text = {'Targettizzazione dataset'; '(grave/non grave)'};
            app.CreazionedatidiTraineTestPanel = uipanel(app.PreprocessingTab);
            app.CreazionedatidiTraineTestPanel.Title = 'Creazione dati di Train e Test';
            app.TrainSliderLabel = uilabel(app.CreazionedatidiTraineTestPanel);
            app.TrainSliderLabel.Text = 'Train %';
            app.TrainSlider = uislider(app.CreazionedatidiTraineTestPanel);
            app.TrainSlider.MajorTicks = [0 20 40 60 80 100];
            app.TrainSlider.ValueChangingFcn = createCallbackFcn(app, @TrainSliderValueChanging, true);
            app.TrainSlider.MinorTicks = [10 30 50 70 90];
            app.TrainSlider.Value = 70;
            app.TestSliderLabel = uilabel(app.CreazionedatidiTraineTestPanel);
            app.TestSliderLabel.Text = 'Test %';
            app.TestSlider = uislider(app.CreazionedatidiTraineTestPanel);
            app.TestSlider.ValueChangingFcn = createCallbackFcn(app, @TestSliderValueChanging, true);
            app.TestSlider.MinorTicks = [10 30 50 70 90];
            app.TestSlider.Value = 30;
            app.DivididatasetButton = uibutton(app.CreazionedatidiTraineTestPanel, 'push');
            app.DivididatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DivididatasetButtonPushed, true);
            app.DivididatasetButton.Text = 'Dividi dataset';
            app.NormalizzazionedatiPanel = uipanel(app.PreprocessingTab);
            app.NormalizzazionedatiPanel.Title = 'Normalizzazione dati';
            app.NormalizzadatiButton = uibutton(app.NormalizzazionedatiPanel, 'push');
            app.NormalizzadatiButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizzadatiButtonPushed, true);
            app.NormalizzadatiButton.Text = 'Normalizza dati';
            app.AumentoRiduzionecampionitrainingsetPanel = uipanel(app.PreprocessingTab);
            app.AumentoRiduzionecampionitrainingsetPanel.Title = 'Aumento/Riduzione campioni training set';
            app.OversamplingButton = uibutton(app.AumentoRiduzionecampionitrainingsetPanel, 'push');
            app.OversamplingButton.ButtonPushedFcn = createCallbackFcn(app, @OversamplingButtonPushed, true);
            app.OversamplingButton.Text = 'Oversampling';
            app.UndersamplingButton = uibutton(app.AumentoRiduzionecampionitrainingsetPanel, 'push');
            app.UndersamplingButton.Enable = 'off';
            app.UndersamplingButton.Text = 'Undersampling';
            app.RiduzionedimensionalitTab = uitab(app.TabGroup);
            app.RiduzionedimensionalitTab.Title = 'Riduzione dimensionalità';
            app.PrincipalComponentAnalysisPanel = uipanel(app.RiduzionedimensionalitTab);
            app.PrincipalComponentAnalysisPanel.Title = 'Principal Component Analysis';
            app.EseguiPCAButton = uibutton(app.PrincipalComponentAnalysisPanel, 'push');
            app.EseguiPCAButton.ButtonPushedFcn = createCallbackFcn(app, @EseguiPCAButtonPushed, true);
            app.EseguiPCAButton.Text = 'Esegui PCA';
            app.VarianzadapreservareSpinnerLabel = uilabel(app.PrincipalComponentAnalysisPanel);
            app.VarianzadapreservareSpinnerLabel.Text = 'Varianza da preservare';
            app.VarianzadapreservareSpinner = uispinner(app.PrincipalComponentAnalysisPanel);
            app.VarianzadapreservareSpinner.LowerLimitInclusive = 'off';
            app.VarianzadapreservareSpinner.UpperLimitInclusive = 'off';
            app.VarianzadapreservareSpinner.Limits = [0 100];
            app.VarianzadapreservareSpinner.ValueDisplayFormat = '%5.0f %%';
            app.VarianzadapreservareSpinner.Value = 95;
            app.tDistributedStochasticNeighborEmbeddingPanel = uipanel(app.RiduzionedimensionalitTab);
            app.tDistributedStochasticNeighborEmbeddingPanel.Title = 't-Distributed Stochastic Neighbor Embedding';
            app.EseguitSNEButton = uibutton(app.tDistributedStochasticNeighborEmbeddingPanel, 'push');
            app.EseguitSNEButton.ButtonPushedFcn = createCallbackFcn(app, @EseguitSNEButtonPushed, true);
            app.EseguitSNEButton.Text = 'Esegui t-SNE';
            app.NumerodifeaturesdapreservareSpinnerLabel = uilabel(app.tDistributedStochasticNeighborEmbeddingPanel);
            app.NumerodifeaturesdapreservareSpinnerLabel.Text = {'Numero di features da'; 'preservare'};
            app.NumerodifeaturesdapreservareSpinner = uispinner(app.tDistributedStochasticNeighborEmbeddingPanel);
            app.NumerodifeaturesdapreservareSpinner.LowerLimitInclusive = 'off';
            app.NumerodifeaturesdapreservareSpinner.UpperLimitInclusive = 'off';
            app.NumerodifeaturesdapreservareSpinner.Limits = [1 Inf];
            app.NumerodifeaturesdapreservareSpinner.ValueDisplayFormat = '%5.0f';
            app.NumerodifeaturesdapreservareSpinner.Value = 2;
            app.PerplexitySpinnerLabel = uilabel(app.tDistributedStochasticNeighborEmbeddingPanel);
            app.PerplexitySpinnerLabel.Text = 'Perplexity';
            app.PerplexitySpinner = uispinner(app.tDistributedStochasticNeighborEmbeddingPanel);
            app.PerplexitySpinner.LowerLimitInclusive = 'off';
            app.PerplexitySpinner.UpperLimitInclusive = 'off';
            app.PerplexitySpinner.Limits = [1 Inf];
            app.PerplexitySpinner.ValueDisplayFormat = '%5.0f';
            app.PerplexitySpinner.Value = 10;
            app.RiduzionedelleFeaturesLabel = uilabel(app.RiduzionedimensionalitTab);
            app.RiduzionedelleFeaturesLabel.Text = 'Riduzione delle Features';
            app.SelezionedelleFeaturesLabel = uilabel(app.RiduzionedimensionalitTab);
            app.SelezionedelleFeaturesLabel.Text = 'Selezione delle Features';
            app.StatistichePanel = uipanel(app.RiduzionedimensionalitTab);
            app.StatistichePanel.Title = 'Statistiche';
            app.EseguiTestIpotesiButton = uibutton(app.StatistichePanel, 'push');
            app.EseguiTestIpotesiButton.ButtonPushedFcn = createCallbackFcn(app, @EseguiTestIpotesiButtonPushed, true);
            app.EseguiTestIpotesiButton.Text = 'Esegui Test Ipotesi';
            app.CalcolareFoldChangeCheckBox = uicheckbox(app.StatistichePanel);
            app.CalcolareFoldChangeCheckBox.Text = 'Calcolare Fold Change';
            app.CorrezionepValueCheckBox = uicheckbox(app.StatistichePanel);
            app.CorrezionepValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrezionepValueCheckBoxValueChanged, true);
            app.CorrezionepValueCheckBox.Text = 'Correzione p-Value';
            app.MetododicorrezioneDropDownLabel = uilabel(app.StatistichePanel);
            app.MetododicorrezioneDropDownLabel.Text = 'Metodo di correzione';
            app.MetododicorrezioneDropDown = uidropdown(app.StatistichePanel);
            app.MetododicorrezioneDropDown.Items = {'Bonferroni', 'Sidak', 'Holm-Bonferroni'};
            app.MetododicorrezioneDropDown.Enable = 'off';
            app.MetododicorrezioneDropDown.Value = 'Bonferroni';
            app.AlphaSpinnerLabel = uilabel(app.StatistichePanel);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.StatistichePanel);
            app.AlphaSpinner.Step = 0.01;
            app.AlphaSpinner.Limits = [0 1];
            app.AlphaSpinner.ValueDisplayFormat = '%11.2g';
            app.AlphaSpinner.Value = 0.05;
            app.FeaturedaconservarePanel = uipanel(app.RiduzionedimensionalitTab);
            app.FeaturedaconservarePanel.Title = 'Feature da conservare';
            app.ConservaButton = uibutton(app.FeaturedaconservarePanel, 'push');
            app.ConservaButton.ButtonPushedFcn = createCallbackFcn(app, @ConservaButtonPushed, true);
            app.ConservaButton.Text = 'Conserva';
            app.QuantefeatureconservareSpinnerLabel = uilabel(app.FeaturedaconservarePanel);
            app.QuantefeatureconservareSpinnerLabel.Text = 'Quante feature conservare?';
            app.QuantefeatureconservareSpinner = uispinner(app.FeaturedaconservarePanel);
            app.QuantefeatureconservareSpinner.LowerLimitInclusive = 'off';
            app.QuantefeatureconservareSpinner.Limits = [0 Inf];
            app.QuantefeatureconservareSpinner.ValueDisplayFormat = '%11g';
            app.QuantefeatureconservareSpinner.Value = 20;
            app.AnalisiTab = uitab(app.TabGroup);
            app.AnalisiTab.Title = 'Analisi';
            app.ReteNeuralePanel = uipanel(app.AnalisiTab);
            app.ReteNeuralePanel.Title = 'Rete Neurale';
            app.NeuroniLayer1SpinnerLabel = uilabel(app.ReteNeuralePanel);
            app.NeuroniLayer1SpinnerLabel.Text = 'Neuroni Layer 1';
            app.NeuroniLayer1Spinner = uispinner(app.ReteNeuralePanel);
            app.NeuroniLayer1Spinner.Limits = [1 Inf];
            app.NeuroniLayer1Spinner.Value = 10;
            app.NeuroniLayer2SpinnerLabel = uilabel(app.ReteNeuralePanel);
            app.NeuroniLayer2SpinnerLabel.Text = 'Neuroni Layer 2';
            app.NeuroniLayer2Spinner = uispinner(app.ReteNeuralePanel);
            app.NeuroniLayer2Spinner.Limits = [0 Inf];
            app.NeuroniLayer2Spinner.Value = 4;
            app.IterazionimassimeLabel = uilabel(app.ReteNeuralePanel);
            app.IterazionimassimeLabel.Text = {'Iterazioni'; 'massime'};
            app.IterazionimassimeSpinner = uispinner(app.ReteNeuralePanel);
            app.IterazionimassimeSpinner.Limits = [1 Inf];
            app.IterazionimassimeSpinner.ValueDisplayFormat = '%11g';
            app.IterazionimassimeSpinner.Value = 1500;
            app.EseguiaddestramentoconGAButton = uibutton(app.AnalisiTab, 'push');
            app.EseguiaddestramentoconGAButton.ButtonPushedFcn = createCallbackFcn(app, @EseguiaddestramentoconGAButtonPushed, true);
            app.EseguiaddestramentoconGAButton.Text = 'Esegui addestramento con GA';
            app.AlgoritmoGeneticoPanel = uipanel(app.AnalisiTab);
            app.AlgoritmoGeneticoPanel.Title = 'Algoritmo Genetico';
            app.PopolazioneSpinnerLabel = uilabel(app.AlgoritmoGeneticoPanel);
            app.PopolazioneSpinnerLabel.Text = 'Popolazione';
            app.PopolazioneSpinner = uispinner(app.AlgoritmoGeneticoPanel);
            app.PopolazioneSpinner.Limits = [2 Inf];
            app.PopolazioneSpinner.ValueDisplayFormat = '%11g';
            app.PopolazioneSpinner.Value = 50;
            app.IndividuiElitSpinnerLabel = uilabel(app.AlgoritmoGeneticoPanel);
            app.IndividuiElitSpinnerLabel.Text = 'Individui Elitè';
            app.IndividuiElitSpinner = uispinner(app.AlgoritmoGeneticoPanel);
            app.IndividuiElitSpinner.Limits = [0 Inf];
            app.IndividuiElitSpinner.ValueDisplayFormat = '%11g';
            app.IndividuiElitSpinner.Value = 2;
            app.LimiteFunzFitnessSpinnerLabel = uilabel(app.AlgoritmoGeneticoPanel);
            app.LimiteFunzFitnessSpinnerLabel.Text = 'Limite Funz. Fitness';
            app.LimiteFunzFitnessSpinner = uispinner(app.AlgoritmoGeneticoPanel);
            app.LimiteFunzFitnessSpinner.Step = 0.001;
            app.LimiteFunzFitnessSpinner.Limits = [0 Inf];
            app.LimiteFunzFitnessSpinner.Value = 0.0001;
            app.NumerodistallimaxSpinnerLabel = uilabel(app.AlgoritmoGeneticoPanel);
            app.NumerodistallimaxSpinnerLabel.Text = 'Numero di stalli max';
            app.NumerodistallimaxSpinner = uispinner(app.AlgoritmoGeneticoPanel);
            app.NumerodistallimaxSpinner.Limits = [1 Inf];
            app.NumerodistallimaxSpinner.Value = 20;
            app.NumerodigenerezionimaxSpinnerLabel = uilabel(app.AlgoritmoGeneticoPanel);
            app.NumerodigenerezionimaxSpinnerLabel.Text = 'Numero di generezioni max';
            app.NumerodigenerezionimaxSpinner = uispinner(app.AlgoritmoGeneticoPanel);
            app.NumerodigenerezionimaxSpinner.Limits = [1 Inf];
            app.NumerodigenerezionimaxSpinner.Value = 400;
            app.MostraIterazioniCheckBox = uicheckbox(app.AlgoritmoGeneticoPanel);
            app.MostraIterazioniCheckBox.Text = 'Mostra Iterazioni';
            app.EseguiaddestramentosenzaGAButton = uibutton(app.AnalisiTab, 'push');
            app.EseguiaddestramentosenzaGAButton.ButtonPushedFcn = createCallbackFcn(app, @EseguiaddestramentosenzaGAButtonPushed, true);
            app.EseguiaddestramentosenzaGAButton.Text = 'Esegui addestramento senza GA';
            % Show the figure after all components are created
            app.ClassificatoreperGDCDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Classificatore_GDC_Data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassificatoreperGDCDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassificatoreperGDCDataUIFigure)
        end
    end
end